classdef perovXimager_VER3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Noise2DTextLabel                matlab.ui.control.Label
        ExampleLabel                    matlab.ui.control.Label
        unitsPixelsDropDown             matlab.ui.control.DropDown
        ExamplesDropDown                matlab.ui.control.DropDown
        xbandgapLabel                   matlab.ui.control.Label
        umesEditField                   matlab.ui.control.NumericEditField
        MaterialThicknessLabel_14       matlab.ui.control.Label
        massAttCoefByEnergyEditField    matlab.ui.control.NumericEditField
        MaterialThicknessLabel_13       matlab.ui.control.Label
        E_pairEditField                 matlab.ui.control.NumericEditField
        MaterialThicknessLabel_12       matlab.ui.control.Label
        E_bandGapEditField              matlab.ui.control.NumericEditField
        MaterialThicknessLabel_11       matlab.ui.control.Label
        EmesEditField                   matlab.ui.control.NumericEditField
        MaterialThicknessLabel_10       matlab.ui.control.Label
        massAttCoefEditField            matlab.ui.control.NumericEditField
        MaterialThicknessLabel_9        matlab.ui.control.Label
        thicknessEditField              matlab.ui.control.NumericEditField
        MaterialThicknessLabel_8        matlab.ui.control.Label
        DetectorAreaEditField           matlab.ui.control.NumericEditField
        Detectorareamm2Label_2          matlab.ui.control.Label
        unitsEnergyDropDown             matlab.ui.control.DropDown
        unitsLinAttCoefDropDown         matlab.ui.control.DropDown
        unitsDoseNoiseDropDown          matlab.ui.control.DropDown
        unitsDoseSignalDropDown         matlab.ui.control.DropDown
        unitsNoiseDropDown              matlab.ui.control.DropDown
        unitsSignalDropDown             matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        fileAddressDetNoise2D           matlab.ui.control.TextArea
        fileAddressPixelCoord           matlab.ui.control.TextArea
        fileAddressESF                  matlab.ui.control.TextArea
        fileAddressEnergy               matlab.ui.control.TextArea
        fileAddressLinCoef              matlab.ui.control.TextArea
        fileAddressDoseN                matlab.ui.control.TextArea
        fileAddressNoise                matlab.ui.control.TextArea
        fileAddressDoseS                matlab.ui.control.TextArea
        fileAddressSignal               matlab.ui.control.TextArea
        DetectorNoise2DButton           matlab.ui.control.Button
        PixelspositionButton            matlab.ui.control.Button
        ESFButton                       matlab.ui.control.Button
        EnergyButton                    matlab.ui.control.Button
        LinattencoefButton              matlab.ui.control.Button
        FileinputLabel                  matlab.ui.control.Label
        DosenoiseButton                 matlab.ui.control.Button
        NoiseButton                     matlab.ui.control.Button
        DosesignalButton                matlab.ui.control.Button
        SignalButton                    matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ColorButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SinglepixelcharacterizationTab  matlab.ui.container.Tab
        onePhotonDoseTextArea           matlab.ui.control.TextArea
        DetectionefficiencyLabel_9      matlab.ui.control.Label
        onlyif0dosepointwasmeasuredLabel  matlab.ui.control.Label
        interceptCorrectionCheckBox     matlab.ui.control.CheckBox
        unitsDoseDQEDropDown            matlab.ui.control.DropDown
        SetDoseRangeLabel               matlab.ui.control.Label
        stepDoseEditField               matlab.ui.control.NumericEditField
        stepEditFieldLabel_2            matlab.ui.control.Label
        maxDoseEditField                matlab.ui.control.NumericEditField
        maxEditFieldLabel_2             matlab.ui.control.Label
        minDoseEditField                matlab.ui.control.NumericEditField
        minEditFieldLabel_2             matlab.ui.control.Label
        SpecifythedoseinphotonsLabel    matlab.ui.control.Label
        DQEcurve3EditField              matlab.ui.control.NumericEditField
        curve3EditFieldLabel_2          matlab.ui.control.Label
        DQEcurve2EditField              matlab.ui.control.NumericEditField
        curve2EditFieldLabel_2          matlab.ui.control.Label
        DQEcurve1EditField              matlab.ui.control.NumericEditField
        curve1EditFieldLabel_2          matlab.ui.control.Label
        NEDphotonTextArea               matlab.ui.control.TextArea
        DetectionefficiencyLabel_8      matlab.ui.control.Label
        NEDdoseTextArea                 matlab.ui.control.TextArea
        DetectionefficiencyLabel_7      matlab.ui.control.Label
        DE_2TextArea                    matlab.ui.control.TextArea
        DetectionefficiencyLabel_6      matlab.ui.control.Label
        DETextArea                      matlab.ui.control.TextArea
        DetectionefficiencyLabel_5      matlab.ui.control.Label
        TheoreticalSensitivityTextArea  matlab.ui.control.TextArea
        TheoreticalsensitivityTextAreaLabel_2  matlab.ui.control.Label
        DQE_E_plot                      matlab.ui.control.UIAxes
        NED_E_plot                      matlab.ui.control.UIAxes
        DE_E_plot                       matlab.ui.control.UIAxes
        AE_plot                         matlab.ui.control.UIAxes
        DQE_dose_plot                   matlab.ui.control.UIAxes
        NoiseToDose                     matlab.ui.control.UIAxes
        SignalToDose                    matlab.ui.control.UIAxes
        ArraydetectorcharacterizationTab  matlab.ui.container.Tab
        n_1fracn_mathrmphotonpixelmathrmSNR_mathrmout1Acdotf_202Label  matlab.ui.control.Label
        n1TextArea                      matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        NPSTextLabel                    matlab.ui.control.Label
        ApproximationLabel              matlab.ui.control.Label
        fitLabel                        matlab.ui.control.Label
        fit_u_EditField                 matlab.ui.control.NumericEditField
        fit_sigma_EditField             matlab.ui.control.NumericEditField
        fit_A_EditField                 matlab.ui.control.NumericEditField
        guessLabel                      matlab.ui.control.Label
        gaussanLabel                    matlab.ui.control.Label
        guess_sigma_EditField           matlab.ui.control.NumericEditField
        ALabel_3                        matlab.ui.control.Label
        guess_u_EditField               matlab.ui.control.NumericEditField
        ALabel_2                        matlab.ui.control.Label
        guess_A_EditField               matlab.ui.control.NumericEditField
        ALabel                          matlab.ui.control.Label
        MTFlimitEditField               matlab.ui.control.NumericEditField
        limitlpmmLabel_2                matlab.ui.control.Label
        NNPS_plot                       matlab.ui.control.UIAxes
        DQE_f_plot                      matlab.ui.control.UIAxes
        NPS_plot                        matlab.ui.control.UIAxes
        Noise2D_plot                    matlab.ui.control.UIAxes
        MTF_plot                        matlab.ui.control.UIAxes
        LSF_plot                        matlab.ui.control.UIAxes
        ESF_plot                        matlab.ui.control.UIAxes
        TransientTab                    matlab.ui.container.Tab
        RiseTimeEditField               matlab.ui.control.EditField
        RisetimeEditFieldLabel          matlab.ui.control.Label
        timeGuessValuesLabel            matlab.ui.control.Label
        guessTimeStopPointEditField     matlab.ui.control.NumericEditField
        guessvalueLabel_2               matlab.ui.control.Label
        guessTimeStartPointEditField    matlab.ui.control.NumericEditField
        guessvalueLabel                 matlab.ui.control.Label
        f3dB_EditField                  matlab.ui.control.NumericEditField
        f_3dBHzLabel                    matlab.ui.control.Label
        fileAddressTime                 matlab.ui.control.TextArea
        unitsTimeDropDown               matlab.ui.control.DropDown
        TimeButton                      matlab.ui.control.Button
        fileAddressSignalTransient      matlab.ui.control.TextArea
        SignalTransientButton           matlab.ui.control.Button
        Transient_plot                  matlab.ui.control.UIAxes
        VitaliiBartoshKostiantynSakhatskyiKovalenkoGroupETHZurichLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        d;
        detectorArea;
        e_charge = 1.602*10^-19;
        Signal;
        Signal_multiplier;
        Signal_loaded;
        DoseSignal;
        DoseSignal_loaded;
        DoseSignal_multiplier;
        Noise;
        Noise_multiplier;
        Noise_loaded;
        DoseNoise;
        DoseNoise_loaded;
        DoseNoise_multiplier;
        doseCoef; %for DQE(dose plot)
        u;
        u_loaded;
        E_u;
        E_u_loaded;
        ESF;
        distance;
        distanceMultiplier;
        new_distance;
        fit_distance;
        %coordinates;
        coordinates_loaded;
        NoiseMatrix;
        eBandGap;
        ePair; %electron-hole pair creation energy
        u_p;
        senTheory;
        slopeS;
        slopeS_theory;
        slopeN;
        DE;
        DE_2;
        u_p_E_av; % 4 kev*cm^2*g^-1 // product of x-ray energy and mass attenuation coefficient, averaged 
        OnePhotonDose;
        NED;
        NED_photon;
        dose_dqe;

        DQE_array;
        pixelPitch;
        AE;
        E;
        E_mes;
        u_mes;
        AE_mes;
        DE_E;
        NED_E;
        NED_E_export;
        LSF;
        f;
        ff;
        frequency;
        MTF;
        f2;
        f2_new;
        NPS;
        NPS_av;
        NPS_aprox;
        NNPS;
        NNPS_aprox;
        DQE_f;
        n1;
        SignalTransient_loaded;
        SignalTransient;
        Time_loaded;
        Time;
        timeMultiplier;
        riseTime;
        Example_constants = {'Example_GOS\Signal_GOS.txt',              'a.u.',       'Example_GOS\Dose_signal_GOS.txt',           'uGy',      'Example_GOS\Noise_GOS.txt',               'a.u.',       'Example_GOS\Dose_noise_GOS.txt',           'uGy',       'Example_GOS\u_GOS.txt',              'cm^-1',     'Example_GOS\u_energy_GOS.txt',              'keV',         'Example_GOS/Signal_profile_GOS_pos_III.txt', 'Example_GOS/Distance_GOS_pixels_pos_III_pixels.txt',  'um',        'Example_GOS/Noise_2D_GOS.txt',      0.017,       0.5,          0.462,           10.16,       22,      183,         1.5,             2.8,              0, 300,  0.01,         10^4, 10^5, 10^6,     'uGy',           10,                1,               1000, 0.6,   0.2,     'Example_GOS\GOS_no_data.txt',                'Example_GOS\GOS_no_data.txt',                     'us',        0,   0;
                             'Example_a_Se\Signal_a_Se_ADU.txt',        'a.u.',       'Example_a_Se\Dose_signal_a_Se_mR.txt',      'mR',       'Example_a_Se\Noise_a_Se_ADU.txt',         'a.u.',       'Example_a_Se\Dose_noise_a_Se_ADU.txt',     'mR',        'Example_a_Se\u_a_Se.txt',            'cm^-1',     'Example_a_Se\u_energy_a_Se.txt',            'keV',         'Example_a_Se\Signal_profile_a_Se.txt',       'Example_a_Se\Distance_a_Se.txt',                      'mm',        'Example_a_Se/Noise_2D_a_Se.txt',    0.005,       0.1,          0.54,            10.368,      19.2,    224,         1.99,            2.8,              0, 60,   0.01,         10^2, 10^3, 10^4,     'uGy',           10,                0,               1000, 0.6,   0.1,     'Example_a_Se\a_Se_no_data.txt',              'Example_a_Se\a_Se_no_data.txt',                   'us',        0,   0;
                             'Example_Si\Signal_Si_charge.txt',         'electrons',  'Example_Si\Dose_signal_Si_photons.txt',     'photons',  'Example_Si\Noise_Si_charge.txt',          'electrons',  'Example_Si\Dose_noise_Si_photons.txt',     'photons',   'Example_Si\u_Si.txt',                'cm^-1',     'Example_Si\u_energy_Si.txt',                'keV',         'Example_Si\Signal_profile_Si.txt',           'Example_Si\Distance_Si.txt',                          'mm',        'Example_Si\no_data_Si.txt',         0.003,       0.3,          10,              47,          10,      77.1,        1.11,            2.8,              0, 10,   0.001,        1,    10,   100,      'photons',       10,                1,               1,    0.2,   0.2,     'Example_Si\Transient_signal_Si.txt',         'Example_Si\Transient_signal_time_Si.txt',         'ns',        100, 170;
                             'Example_CdTe\Signal_CdTe_charge.txt',     'electrons',  'Example_CdTe\Dose_signal_CdTe_photons.txt', 'photons',  'Example_CdTe\Noise_CdTe_charge.txt',      'electrons',  'Example_CdTe\Dose_noise_CdTe_photons.txt', 'photons',   'Example_CdTe\u_CdTe_1mm.txt',        'cm^-1',     'Example_CdTe\u_energy_CdTe_1mm.txt',        'keV',         'Example_CdTe\Signal_profile_CdTe.txt',       'Example_CdTe\Distance_CdTe.txt',                      'mm',        'Example_CdTe\no_data_CdTe.txt',     0.01,        1,            0.03041,         1.825,       60,      36.1,        1.5,             2.8,              0, 1,    0.001,        10,   100,  1000,     'photons',       10,                1,               90,   0.6,   0.1,     'Example_CdTe\Transient_signal_CdTe.txt',     'Example_CdTe\Transient_signal_time_CdTe.txt',     'ns',        125, 215;
                             'Example_MAPbI3\Signal_MAPbI3_charge.txt', 'electrons',  'Example_MAPbI3\Dose_signal_MAPbI3_uGy.txt', 'uGy',      'Example_MAPbI3\Noise_MAPbI3_charge.txt',  'electrons',  'Example_MAPbI3\Dose_noise_MAPbI3_uGy.txt', 'uGy',       'Example_MAPbI3\u_MAPbI3_200um.txt',  'cm^-1',     'Example_MAPbI3\u_energy_MAPbI3_200um.txt',  'keV',         'Example_MAPbI3\Signal_profile_MAPbI3.txt',   'Example_MAPbI3\Distance_MAPbI3.txt',                  'mm',        'Example_MAPbI3\no_data_MAPbI3.txt', 0.04,        0.2,          0.7,             10.16,       22,      131,         1.6,             2.8,              0, 1,    0.001,        1,    10,   100,      'uGy',           12,                1,               100,  0.544, 0.5,     'Example_MAPbI3\Transient_signal_MAPbI3.txt', 'Example_MAPbI3\Transient_signal_time_MAPbI3.txt', 'us',        30,  51
                             };
                            % [Signal]                                  [signal unit]  [signal dose]                               [dose unit]  [noise]                                   [noise unit]   [noise dose]                               [dose unit]  [lin. atten. coef.]                 [coef. unit]   [energy]                                   [energy unit]     [array profile]                                [pixel position]                                [position unit]   [2D image noise]                  [det. area]  [thickness]  [mass att coef]  [energy*coef]  [Emes]  [u at Emes]  [band-gap]   [creat.energy coef]   [DQE energy range, step]   [DQE energies]   [DQE enegy unit]   [sp.res]   [noise intercept corr.]   [gauss interp. coef]   [transient signal]                            [transient time]                                  [unit]   [time guess values]
        is_u_loaded = 0; 
        is_Eu_loaded = 0;
        commonPath = 'C:\Users\Public\Documents\';
        color = [0.48 0.82 0.76];
    end
    
    methods (Access = private)
        
        function res = photonToDose(app,ph,uE_coef,detArea)
            res = uE_coef*1.602*10^-17*ph/detArea; %in Gy
        end
        
        function res = u_interpolate(app,query,x,y)
            res = spline(x,y,query);
        end
        
        function res = DQE_E(app,DE_E,NED_E,dose)
             res = DE_E./(1+NED_E/dose);
        end
        
        function out = DQE(app,dose,de,ned)
            out = de/(1+ned/dose);
        end

        function res = findApproxX(app,xApproach, targetY, arrayX, arrayY)
            %yLeft = u_interpolate(app,0.2,arrayX,arrayY);
            yApproach = 0;
            yApproachPos = 0;
            for i = 1:length(arrayY)
                if arrayX(i) == xApproach
                    yApproach = arrayY(i);
                    yApproachPos = i;
                    break;
                end   
            end
            if abs(yApproach - targetY) < 10^-4
                res = xApproach; % STOP of function
            else
                %recursion
                shortArrayX = zeros(1,5);
                shortArrayY = zeros(1,5);
                for i = yApproachPos-2 : yApproachPos + 2
                    shortArrayX(i-(yApproachPos-2)+1) = arrayX(i);
                    shortArrayY(i-(yApproachPos-2)+1) = arrayY(i);
                end
                approxArrayX = shortArrayX(1): (shortArrayX(length(shortArrayX)) - shortArrayX(1))/100 :shortArrayX(length(shortArrayX)); 
                approxArrayY = u_interpolate(app,approxArrayX,shortArrayX,shortArrayY);
                differenceY = targetY;
                for i = 1:length(approxArrayY)
                    if abs(approxArrayY(i) - targetY) < differenceY 
                        differenceY = abs(approxArrayY(i) - targetY);
                        xApproachNew = approxArrayX(i);
                    else 
                        break;
                    end 
                end
                res = findApproxX(app,xApproachNew, targetY, approxArrayX, approxArrayY); %new loop of recursion

            end
        end
        
        function export(app,plot,X,Y,name)
            [file,path] = uiputfile({'*.txt';'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as',name);
            if file(length(file)-2:length(file)) == "pdf"
                exportgraphics(plot,fullfile(path,file),'ContentType','vector');
            elseif file(length(file)-2:length(file)) == "txt"
                xy = [X(:), Y(:)];
                writematrix(xy,fullfile(path,file),'delimiter','tab')
            else
            exportgraphics(plot,fullfile(path,file));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'perovXimager';
        end

        % Button pushed function: SignalButton
        function SignalButtonPushed(app, event)
           
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.Signal_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressSignal.Value = file;
            app.commonPath = path;
            

        end

        % Button pushed function: DosesignalButton
        function DosesignalButtonPushed(app, event)
            
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.DoseSignal_loaded = fscanf(fileID,"%f"); %in ?Gy now
            fclose(fileID);
            app.fileAddressDoseS.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

        isArbitaryUnits = 0;  
        app.Signal = 0;
        app.DoseSignal = 0;
        app.Noise = 0;
        app.DoseNoise = 0;
        app.u = 0;
        app.E_u = 0;
        app.distance = 0;
        app.new_distance = 0;
        app.fit_distance = 0;
        app.DQE_array= 0;
        app.AE = 0;
        app.E = 0;
        app.DE_E = 0;
        app.NED_E = 0;
        app.NED_E_export = 0;
        app.LSF = 0;
        app.f = 0;
        app.frequency = 0;
        app.MTF = 0;
        app.f2 = 0;
        app.f2_new = 0;
        app.NPS = 0;
        app.NNPS = 0;
        app.DQE_f = 0;
        app.ff = 0;
        app.NPS_aprox = 0;
        app.NNPS_aprox = 0;
        OTF = 0;
        app.Time = 0;
        app.SignalTransient = 0;
        app.NPSTextLabel.Visible = 'off';
        app.Noise2D_plot.Visible = 'on';
        app.NPS_plot.Visible = 'on';
        app.NNPS_plot.Visible = 'on';
        app.n1TextArea.Value = "";

        cla(app.SignalToDose);
        cla(app.NoiseToDose);
        cla(app.DQE_dose_plot);
        cla(app.AE_plot);
        cla(app.DQE_E_plot);
        cla(app.DE_E_plot);
        cla(app.NED_E_plot);


            % numerical values
             app.d = app.thicknessEditField.Value*10^-3; % in m
             app.detectorArea = app.DetectorAreaEditField.Value*10^-6; % im m^2
             app.eBandGap = app.E_bandGapEditField.Value*app.e_charge;
            app.ePair = app.E_pairEditField.Value *app.eBandGap;
            app.u_p = 10^-1*app.massAttCoefEditField.Value;
            app.u_p_E_av = app.massAttCoefByEnergyEditField.Value;
            app.senTheory = app.e_charge/app.ePair/app.u_p; % C*m^-2*Gy^-1
            app.OnePhotonDose = photonToDose(app,1,app.u_p_E_av,app.detectorArea); %in Gy
            %signal/dose
            switch(app.unitsSignalDropDown.Value)
                case 'C'
                    app.Signal_multiplier = 1;
                case 'mC'
                    app.Signal_multiplier = 10^-3;
                case 'uC'
                    app.Signal_multiplier = 10^-6;
                case 'a.u.'
                    app.Signal_multiplier = 1;
                    isArbitaryUnits = 1;
                case 'electrons'
                    app.Signal_multiplier = app.e_charge;
            end
            app.Signal = app.Signal_multiplier*app.Signal_loaded;
            switch (app.unitsDoseSignalDropDown.Value)
                case 'Gy'
                    app.DoseSignal_multiplier = 1;
                case 'mGy'
                    app.DoseSignal_multiplier = 10^-3;
                case 'uGy'
                    app.DoseSignal_multiplier = 10^-6;
                case 'mR'
                    app.DoseSignal_multiplier = 8.77*10^-6;
                case 'R'
                    app.DoseSignal_multiplier = 8.77*10^-3;
                case 'photons'
                    app.DoseSignal_multiplier = app.OnePhotonDose;
            end
            
            app.DoseSignal = app.DoseSignal_multiplier*app.DoseSignal_loaded;
             
             scatter(app.SignalToDose,app.DoseSignal/app.DoseSignal_multiplier,app.Signal/app.Signal_multiplier, 25, 'MarkerFaceColor',app.color);
             S_D_fit_pol = polyfit(app.DoseSignal,app.Signal,1);
             SD_fit = polyval(S_D_fit_pol, app.DoseSignal);
             hold (app.SignalToDose,'on');
             xlabel(app.SignalToDose,"dose, " +app.unitsDoseSignalDropDown.Value);
             ylabel(app.SignalToDose,"Signal, " +app.unitsSignalDropDown.Value);
             plot(app.SignalToDose,app.DoseSignal/app.DoseSignal_multiplier,SD_fit/app.Signal_multiplier,'Color',app.color,'LineWidth',1.5) %signal/dose dependence
             hold (app.SignalToDose,'off');

             
            app.slopeS = S_D_fit_pol(1);
            app.slopeS_theory = app.senTheory*app.detectorArea;
            if isArbitaryUnits == 1
                app.DE = 'a.u. used';
            else
                app.DE = app.slopeS / app.slopeS_theory;
            end
            app.TheoreticalSensitivityTextArea.Value = app.senTheory*100 + " uC Gy^-1 cm^-2";
            app.DETextArea.Value = num2str(app.DE);
            app.onePhotonDoseTextArea.Value = num2str(app.OnePhotonDose/10^-9 + " nGy"); %in nGy

            %sq Noise/dose
            switch(app.unitsNoiseDropDown.Value)
                case 'C'
                    app.Noise_multiplier = 1;
                case 'mC'
                    app.Noise_multiplier = 10^-3;
                case 'uC'
                    app.Noise_multiplier = 10^-6;
                case 'a.u.'
                    app.Noise_multiplier = 1;
                    isArbitaryUnits = 1;
                case 'electrons'
                    app.Noise_multiplier = app.e_charge;
            end
            app.Noise = app.Noise_loaded .* app.Noise_multiplier;
            switch (app.unitsDoseNoiseDropDown.Value)
                case 'Gy'
                    app.DoseNoise_multiplier = 1;
                case 'mGy'
                    app.DoseNoise_multiplier = 10^-3;
                case 'uGy'
                    app.DoseNoise_multiplier = 10^-6;
                case 'mR'
                    app.DoseNoise_multiplier = 8.77*10^-6;
                case 'R'
                    app.DoseNoise_multiplier = 8.77*10^-3;
                case 'photons'
                    app.DoseNoise_multiplier = app.OnePhotonDose;
            end
            app.DoseNoise = app.DoseNoise_multiplier*app.DoseNoise_loaded;
            N_D_fit_pol = polyfit(app.DoseNoise,app.Noise.^2,1);
            ND_fit = polyval(N_D_fit_pol, app.DoseNoise);
            app.slopeN = N_D_fit_pol(1);
            
            legend(app.NoiseToDose,'Location','northwest');
            scatter(app.NoiseToDose,app.DoseNoise/app.DoseNoise_multiplier,(app.Noise/app.Noise_multiplier).^2, 25, 'MarkerFaceColor',app.color,'DisplayName',"data points"); %noise/dose dependence
            hold(app.NoiseToDose, 'on');
             xlabel(app.NoiseToDose,"dose, " +app.unitsDoseNoiseDropDown.Value);
             ylabel(app.NoiseToDose,"Noise^2, " +app.unitsNoiseDropDown.Value+ "^2");
            plot(app.NoiseToDose,app.DoseNoise/app.DoseNoise_multiplier,ND_fit/(app.Noise_multiplier^2),'Color',app.color,'LineWidth',1.5,'DisplayName',"original fit"); %noise/dose dependence
            new_noise_intercept = app.Noise(1)^2;
            if app.interceptCorrectionCheckBox.Value == 1
                y2 = N_D_fit_pol(1)*app.DoseNoise + new_noise_intercept;
                plot(app.NoiseToDose, app.DoseNoise/app.DoseNoise_multiplier, y2/(app.Noise_multiplier^2),'Color',app.color*0.6,'LineWidth',1.5,'DisplayName',"corrected fit");
            end
            hold(app.NoiseToDose, 'off');
            %
            app.OnePhotonDose = photonToDose(app,1,app.u_p_E_av,app.detectorArea); %in Gy
            app.DE_2 = app.slopeS^2*app.OnePhotonDose/app.slopeN;
            if app.interceptCorrectionCheckBox.Value == 1
                app.NED = new_noise_intercept/app.slopeN; %in Gy
            else
                app.NED = N_D_fit_pol(2)/app.slopeN;
            end
            app.NED_photon = app.NED/app.OnePhotonDose;
            app.DE_2TextArea.Value = num2str(app.DE_2);
            app.NEDdoseTextArea.Value = num2str(app.NED/10^-6) + " uGy";
            app.NEDphotonTextArea.Value = num2str(app.NED_photon);
            app.doseCoef = 0;
            %DQE
            switch(app.unitsDoseDQEDropDown.Value)
                case 'Gy'
                    app.doseCoef = 1;
                case 'mGy'
                    app.doseCoef = 10^-3;
                case 'uGy'
                    app.doseCoef = 10^-6;
                case 'mR'
                    app.doseCoef = 8.77*10^-6;
                case 'R'
                    app.doseCoef = 8.77*10^-3;
                case 'photons'
                    app.doseCoef = app.OnePhotonDose;
            end
            minDose = app.doseCoef*app.minDoseEditField.Value;
            maxDose = app.doseCoef*app.maxDoseEditField.Value;
            stepDose = app.doseCoef*app.stepDoseEditField.Value;
            app.dose_dqe = minDose:stepDose:maxDose;
            app.DQE_array = zeros(size(app.dose_dqe));
            for i=1:size(app.dose_dqe,2)
                 app.DQE_array(i)=DQE(app,app.dose_dqe(i), app.DE_2,app.NED);
            end
            
            xlabel(app.DQE_dose_plot,"dose, "+app.unitsDoseDQEDropDown.Value);
            xlim(app.DQE_dose_plot,[0 app.maxDoseEditField.Value]);
            ylim(app.DQE_dose_plot,[0 1]);
            plot(app.DQE_dose_plot, app.dose_dqe/app.doseCoef, app.DQE_array,'Color',app.color,'LineWidth',1.5);
            %Energy dependencies
            switch (app.unitsLinAttCoefDropDown.Value)
                    case 'cm^-1'
                        app.u = 10^2*app.u_loaded;
                    case 'm^-1'
                        app.u = app.u_loaded;
            end
                switch(app.unitsEnergyDropDown.Value)
                    case 'keV'
                        app.E_u = app.E_u_loaded;
                    case 'eV'
                        app.E_u = 10^-3*app.E_u_loaded;
                    case 'MeV'
                        app.E_u = 10^3*app.E_u_loaded;
                end
            if app.u ~= 0 & app.E_u ~= 0
                
                app.E = 1 :app.E_u(length(app.E_u))/2000:app.E_u(length(app.E_u));
                app.AE = 1 - exp(-app.d*u_interpolate(app,app.E,app.E_u, app.u)); %Attenuation efficiency
                HAE = u_interpolate(app,log(2)/app.d,app.u,app.E_u);
                cla(app.AE_plot);
                xlim(app.AE_plot, [0 app.E(length(app.E))]);
                ylim(app.AE_plot,[0 1.2]);
                plot(app.AE_plot, app.E, app.AE,'Color',app.color,'LineWidth',1.5);
                text(app.AE_plot,0.4*app.E(length(app.E)),1.1,"E_{1/2} = "+ num2str(HAE)+ " keV",'FontSize',14);
              
                app.E_mes = app.EmesEditField.Value;
                app.u_mes = 10^2*app.umesEditField.Value;
                app.AE_mes = 1 - exp(-app.d*app.u_mes);

                app.DE_E = app.AE.*app.DE_2./app.AE_mes; %energy-dependent DE
                cla(app.DE_E_plot);
                xlim(app.DE_E_plot, [0 app.E(length(app.E))]);
                ylim(app.DE_E_plot,[0 1]);
                plot(app.DE_E_plot,app.E,app.DE_E,'Color',app.color,'LineWidth',1.5);
      
                app.NED_E = zeros(size(app.E));
                for i=1:length(app.NED_E)
                    app.NED_E(i)=app.NED*app.E_mes^2/app.AE_mes*app.AE(i)/app.E(i)^2; %energy-dependent NED
                    app.NED_E_export(i) = app.NED/app.DoseNoise_multiplier*app.E_mes^2/app.AE_mes*app.AE(i)/app.E(i)^2;
                end
                cla(app.NED_E_plot);
                xlim(app.NED_E_plot, [0 app.E(length(app.E))]);
                ylabel(app.NED_E_plot,"NED_E, "+app.unitsDoseSignalDropDown.Value);
                plot(app.NED_E_plot,app.E,app.NED_E_export,'Color',app.color,'LineWidth',1.5);

                hold(app.DQE_E_plot,'on');
              
                cla(app.DQE_E_plot);
                
                legend(app.DQE_E_plot);
                xlim(app.DQE_E_plot, [0 app.E(length(app.E))]);
                ylim(app.DQE_E_plot,[0 1]);
                plot(app.DQE_E_plot,app.E, DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve1EditField.Value,app.u_p_E_av,app.detectorArea)),'Color',app.color,'LineWidth',1.5,'DisplayName',num2str(app.DQEcurve1EditField.Value,'%.2e')+" photons" );
                plot(app.DQE_E_plot,app.E, DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve2EditField.Value,app.u_p_E_av,app.detectorArea)),'Color',app.color*0.8,'LineWidth',1.5,'DisplayName',num2str(app.DQEcurve2EditField.Value,'%.2e')+" photons" );
                plot(app.DQE_E_plot,app.E, DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve3EditField.Value,app.u_p_E_av,app.detectorArea)),'Color',app.color*0.6,'LineWidth',1.5,'DisplayName',num2str(app.DQEcurve3EditField.Value,'%.2e')+" photons" );
                hold(app.DQE_E_plot,'off');
            end
        

            %% transient %%
            cla(app.Transient_plot);
            app.RiseTimeEditField.Value = "";
                app.f3dB_EditField.Value = 0;
            if length(app.SignalTransient_loaded) > 3 && length(app.Time_loaded) > 3
                app.SignalTransient = app.SignalTransient_loaded ./ max(app.SignalTransient_loaded);
                
                switch(app.unitsTimeDropDown.Value)
                    case 's'
                        app.timeMultiplier = 1;
                    case 'ms'
                         app.timeMultiplier = 10^-3;
                    case 'us'
                        app.timeMultiplier = 10^-6;
                    case 'ns'
                        app.timeMultiplier = 10^-9;
                end
                app.Time = app.Time_loaded .* app.timeMultiplier;
                
                %search of the closest time value for 10%
                difference10 = app.Time(length(app.Time));
                timeApproach10 = 0;
                for i = 1:length(app.Time)
                    if abs(app.Time(i) - app.guessTimeStartPointEditField.Value*app.timeMultiplier) < difference10 
                        difference10 = abs(app.Time(i) - app.guessTimeStartPointEditField.Value*app.timeMultiplier);
                        timeApproach10 = app.Time(i);
                    else 
                        break;
                    end 
                end
    
                %search of the closest time value for 90%
                difference90 = app.Time(length(app.Time));
                timeApproach90 = 0;
                for i = 1:length(app.Time)
                    if abs(app.Time(i) - app.guessTimeStopPointEditField.Value*app.timeMultiplier) < difference90 
                        difference90 = abs(app.Time(i) - app.guessTimeStopPointEditField.Value*app.timeMultiplier);
                        timeApproach90 = app.Time(i);
                    else 
                        break;
                    end 
                end
                time10 = findApproxX(app,timeApproach10, 0.1, app.Time, app.SignalTransient);
                time90 = findApproxX(app,timeApproach90, 0.9, app.Time, app.SignalTransient);
                hold(app.Transient_plot,'on');
                scatter(app.Transient_plot, app.Time_loaded, app.SignalTransient_loaded, 25, 'MarkerFaceColor',app.color);
                xline(app.Transient_plot,time10/app.timeMultiplier, '--');
                xline(app.Transient_plot,time90/app.timeMultiplier, '--');
                xlabel(app.Transient_plot, "time, " + app.unitsTimeDropDown.Value);
                hold(app.Transient_plot,'off');
                app.riseTime =  time90 - time10;
                app.RiseTimeEditField.Value = num2str(app.riseTime / app.timeMultiplier) + " " + convertCharsToStrings(app.unitsTimeDropDown.Value);
                app.f3dB_EditField.Value = log(9)/2/pi/app.riseTime;
            end
            
            %% Arrray detector figures %%
            cla(app.ESF_plot);
            cla(app.LSF_plot);
            cla(app.MTF_plot);
            cla(app.Noise2D_plot);
            cla(app.NPS_plot);
            cla(app.NNPS_plot);
            cla(app.DQE_f_plot);
            switch(app.unitsPixelsDropDown.Value)
                case('um')
                    app.distanceMultiplier =  10^-3; %% in mm now
                case('mm')
                    app.distanceMultiplier = 1;
            end
            app.distance = app.coordinates_loaded * app.distanceMultiplier;
            app.pixelPitch = (app.distance(2) - app.distance(1))* app.distanceMultiplier; % units - defined by app.distanceMultiplier
            if length(app.ESF) > 3 & length(app.distance) > 3   %ESF & Noise2D
                xlim(app.ESF_plot,[0 app.distance(length(app.distance))]);
                scatter(app.ESF_plot,app.distance,app.ESF, 25, 'MarkerFaceColor',app.color);
                app.new_distance = zeros(size(app.distance,1)-1,1);
                for i=1:(size(app.new_distance,1))
                   app.new_distance(i) = app.distance(i);
                end
                %LSF
               app.ApproximationLabel.Visible = 'on';
               app.LSF = zeros(1, length(app.new_distance) );
                for i=1:length(app.new_distance)
                    app.LSF(i) = (app.ESF(i+1) - app.ESF(i)) / (app.distance(i+1) - app.distance(i) );
                end
                [xData, yData] = prepareCurveData( app.new_distance, app.LSF );
                ft = fittype( 'a/sqrt(2*pi)/ro*exp(-(x-u)^2/2/ro^2)', 'independent', 'x', 'dependent', 'y' );
                opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
                opts.Algorithm = 'Levenberg-Marquardt';
                opts.Display = 'Off';
                opts.MaxIter = 1000;
                opts.StartPoint = [app.guess_A_EditField.Value app.guess_u_EditField.Value app.guess_sigma_EditField.Value];
                opts.TolFun = 1e-12;
                opts.TolX = 1e-12;
                % Fit model to data.
                [fitresult, gof] = fit( xData, yData, ft, opts );
                app.fit_distance = 0:app.new_distance(length(app.new_distance))/1000:app.new_distance(length(app.new_distance));
                gauss = fitresult.a/sqrt(2*pi)/fitresult.ro*exp(-(app.fit_distance-fitresult.u).^2/2/fitresult.ro^2);
                app.fit_A_EditField.Value = fitresult.a;
                app.fit_sigma_EditField.Value = fitresult.ro;
                app.fit_u_EditField.Value = fitresult.u;
                app.ApproximationLabel.Visible = 'off';
                hold(app.LSF_plot, 'on');
                ylim(app.LSF_plot, [-0.2*max(app.LSF) 1.2*max(app.LSF)] );
                scatter(app.LSF_plot,app.new_distance,app.LSF, 25, 'MarkerFaceColor',app.color); %LSF
                plot(app.LSF_plot,app.fit_distance,gauss,'Color',app.color*0.6) % fit function
                hold(app.LSF_plot, 'off');
                %MTF
               
                gaussReal = @(x,f) (fitresult.a/sqrt(2*pi)/fitresult.ro*exp(-(x-fitresult.u).^2/2/fitresult.ro^2) ) .* exp(-1i*2*pi*f*x);
                FFT_gauss = @(f) integral( @(x) gaussReal(x,f), 0, app.new_distance(length(app.new_distance) ));
                app.ff = 0:0.1:app.MTFlimitEditField.Value;
                OTF = zeros(1, length(app.ff) );
                for i=1:length(OTF)
                    OTF(i) = FFT_gauss(app.ff(i));
                end
                app.MTF = abs(OTF) ./ abs(OTF(1));
                
                plot(app.MTF_plot,app.ff,app.MTF,'Color',app.color,'LineWidth',1.5);
                xlim(app.MTF_plot,[0,app.MTFlimitEditField.Value]);
                spatialRes = u_interpolate(app,0.2,app.MTF,app.ff);
                text(app.MTF_plot,round(app.MTFlimitEditField.Value*0.4),0.95,"f_{sp.res} = "+ num2str(spatialRes)+ " lp/mm",'FontSize',14);

            end
             dx2 = app.new_distance(2)-app.new_distance(1);
             fs2 = 1/dx2; %mm^-1 
             app.f2 = fs2*(0:length(app.new_distance)/1)/length(app.new_distance);
            if size(app.NoiseMatrix,1) > 2 & size(app.NoiseMatrix,2) > 2 & length(app.ESF) > 3 & length(app.distance) > 3
               %Noise matrix
                hold(app.Noise2D_plot,'on');
                image(app.NoiseMatrix,'Parent',app.Noise2D_plot,'CDataMapping','scaled'); %noise from detector
                xlim(app.Noise2D_plot,[0 size(app.NoiseMatrix,1)]);
                ylim(app.Noise2D_plot,[0 size(app.NoiseMatrix,2)]);
                box(app.Noise2D_plot,'on');
                axis(app.Noise2D_plot,'ij');
                hold(app.Noise2D_plot,'off');
                %Set the remaining axes properties
                set(app.Noise2D_plot,'Layer','top');
                colorbar(app.Noise2D_plot);
                %NPS
                Noise_averaged = mean(app.NoiseMatrix,"all");

                [M,N] = size(app.NoiseMatrix);
                app.NPS = zeros(length(app.ff), M); 
                for point = 1:M
                    for freq = 1:length(app.ff)

                        sum = 0;
                        for x = 1:N
                            sum = sum + (app.NoiseMatrix(point,x) - Noise_averaged)*exp(-1i*2*pi*app.ff(freq)*x*dx2);
                        end
                       
                        app.NPS(freq,point) = dx2/N*(abs(sum)).^2;
                        
                    end
                end

                app.NPS_av = zeros(1,length(app.ff));
                for i=1:length(app.ff)
                    app.NPS_av(i) = mean(app.NPS(i,:)); 
                end
           
                app.NNPS = app.NPS_av./app.NPS_av(1); %normalized NPS
                plot(app.NPS_plot, app.ff,app.NPS_av,'Color',app.color,'LineWidth',1.5);
                xlim(app.NPS_plot,[0,app.MTFlimitEditField.Value]);
                plot(app.NNPS_plot, app.ff,app.NNPS,'Color',app.color,'LineWidth',1.5);
                xlim(app.NNPS_plot,[0,app.MTFlimitEditField.Value]);
            else
                app.NPSTextLabel.Visible = 'on';
                app.Noise2D_plot.Visible = 'off';
                app.NPS_plot.Visible = 'off';
                app.NNPS_plot.Visible = 'off';
                 box(app.Noise2D_plot,'off');
               
                colorbar(app.Noise2D_plot,'off');
                app.NNPS = zeros(1,length(app.ff));
                for i=1:length(app.ff)
                    app.NNPS_aprox(i) = 1;
                end 
            end
             app.DQE_f = zeros(1,length(app.MTF));
                for i=1:length(app.MTF)
                        app.DQE_f(i) = app.DE_2 * app.MTF(i).^2./app.NNPS(i);
                end
            
            plot(app.DQE_f_plot,app.ff,app.DQE_f,'Color',app.color,'LineWidth',1.5);
            xlim(app.DQE_f_plot,[0,app.MTFlimitEditField.Value]);
            ylim(app.DQE_f_plot,[0, 1]);

            % n1
            app.n1 = (1 + sqrt(1 + 0.16 * app.DE_2 * app.NED_photon)) / (0.08 * app.DE_2 * dx2^2 * spatialRes^2) ; %in photons
            app.n1TextArea.Value = num2str(app.n1) + " photons";
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.Noise_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressNoise.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: DosenoiseButton
        function DosenoiseButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.DoseNoise_loaded = fscanf(fileID,"%f"); %in ?Gy now
            fclose(fileID);
            app.fileAddressDoseN.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: LinattencoefButton
        function LinattencoefButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.u_loaded = fscanf(fileID,"%f"); %in ?m^-1 now
            fclose(fileID);
            app.fileAddressLinCoef.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: EnergyButton
        function EnergyButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.E_u_loaded = fscanf(fileID,"%f"); %?eV
            fclose(fileID);
            app.fileAddressEnergy.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: ESFButton
        function ESFButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.ESF = fscanf(fileID,"%f"); %no unit
            fclose(fileID);
            app.fileAddressESF.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: PixelspositionButton
        function PixelspositionButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.coordinates_loaded = fscanf(fileID,"%f"); 
            fclose(fileID);
            app.fileAddressPixelCoord.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: DetectorNoise2DButton
        function DetectorNoise2DButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            %app.NoiseMatrix = fscanf(fileID,"%f"); %mm now
            app.NoiseMatrix = readmatrix(fullfile(path,file));
            fclose(fileID);
            app.fileAddressDetNoise2D.Value = file;
            app.commonPath = path;
        end

        % Button down function: SignalToDose
        function SignalToDoseButtonDown(app, event)
            export(app,app.SignalToDose,app.DoseSignal/app.DoseSignal_multiplier,app.Signal/app.Signal_multiplier,'Signal(dose)');
        end

        % Button down function: NoiseToDose
        function NoiseToDoseButtonDown(app, event)
            export(app,app.NoiseToDose,app.DoseNoise/app.DoseNoise_multiplier,(app.Noise/app.Noise_multiplier).^2,'Sq.Noise(dose)');
        end

        % Button down function: DQE_dose_plot
        function DQE_dose_plotButtonDown(app, event)
            export(app,app.DQE_dose_plot,app.dose_dqe/app.doseCoef, app.DQE_array,'DQE(dose)');
        end

        % Button down function: AE_plot
        function AE_plotButtonDown(app, event)
            export(app,app.AE_plot,app.E, app.AE,'AE');
        end

        % Button down function: DQE_E_plot
        function DQE_E_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.txt';'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','DQE(E)');
            if file(length(file)-2:length(file)) == "pdf"
                exportgraphics(app.DQE_E_plot,fullfile(path,file),'ContentType','vector');
            elseif file(length(file)-2:length(file)) == "txt"
                y1 = DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve1EditField.Value,app.u_p_E_av,app.detectorArea));
                y2 = DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve2EditField.Value,app.u_p_E_av,app.detectorArea));
                y3 = DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve3EditField.Value,app.u_p_E_av,app.detectorArea));
                xy = [app.E(:), y1(:), y2(:), y3(:)];
                writematrix(xy,fullfile(path,file),'delimiter','tab')
            else
            exportgraphics(app.DQE_E_plot,fullfile(path,file));
            end
        end

        % Button down function: DE_E_plot
        function DE_E_plotButtonDown(app, event)
            export(app,app.DE_E_plot,app.E, app.DE_E,'DE(E)');
        end

        % Button down function: NED_E_plot
        function NED_E_plotButtonDown(app, event)
            export(app,app.NED_E_plot,app.E, aapp.NED_E_export,'NED(E)');
        end

        % Button down function: ESF_plot
        function ESF_plotButtonDown(app, event)
            export(app,app.ESF_plot,app.distance ./app.distanceMultiplier,app.ESF,'ESF');
        end

        % Button down function: LSF_plot
        function LSF_plotButtonDown(app, event)
            export(app,app.LSF_plot,app.new_distance./app.distanceMultiplier,app.LSF,'LSF');

        end

        % Button down function: MTF_plot
        function MTF_plotButtonDown(app, event)
            export(app,app.MTF_plot,app.ff,app.MTF,'MTF');
        end

        % Button down function: Noise2D_plot
        function Noise2D_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','Noise2D');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.Noise2D_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.Noise2D_plot,fullfile(path,file));
            end
        end

        % Button down function: NPS_plot
        function NPS_plotButtonDown(app, event)
            export(app,app.NPS_plot,app.f2,app.NPS,'NPS');
        end

        % Button down function: NNPS_plot
        function NNPS_plotButtonDown(app, event)
            export(app,app.NNPS_plot,app.ff,app.NNPS_aprox,'NNPS');
        end

        % Button down function: DQE_f_plot
        function DQE_f_plotButtonDown(app, event)
            export(app,app.DQE_f_plot,app.ff,app.DQE_f,'DQE(f)');
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            app.color = uisetcolor([0.48 0.82 0.76],'Select a color for plots');
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            exampleNum = 0;
            app.fit_A_EditField.Value = 0;
            app.fit_sigma_EditField.Value = 0;
            app.fit_u_EditField.Value = 0;
            app.TheoreticalSensitivityTextArea.Value = "";
            app.DETextArea.Value = "";
            app.DE_2TextArea.Value = "";
            app.NEDdoseTextArea.Value = "";
            app.NEDphotonTextArea.Value = "";
            app.n1TextArea.Value = "";
            app.onePhotonDoseTextArea.Value = "";
            cla(app.SignalToDose);
            cla(app.NoiseToDose);
            cla(app.AE_plot);
            cla(app.DQE_dose_plot);
            cla(app.DE_E_plot);
            cla(app.NED_E_plot);
            cla(app.DQE_E_plot);
            cla(app.ESF_plot);
            cla(app.LSF_plot);
            cla(app.MTF_plot);
            cla(app.Noise2D_plot);
            cla(app.NPS_plot);
            cla(app.NNPS_plot);
            cla(app.DQE_f_plot);
            cla(app.Transient_plot);
            
            switch(app.ExamplesDropDown.Value)
                case 'GOS'
                    exampleNum = 1;
                case 'a-Se'
                    exampleNum = 2;
                case 'Si'
                    exampleNum = 3;
                case 'CdTe'
                    exampleNum = 4;
                case 'MAPbI3'
                    exampleNum = 5;
                case 'none'
                    exampleNum = 6;
            end
            if exampleNum == 6
                    app.Signal_loaded = 0;
            app.fileAddressSignal.Value = '';
            app.unitsSignalDropDown.Value = 'C';
            app.DoseSignal_loaded = 0;
            app.fileAddressDoseS.Value = '';
            app.unitsDoseSignalDropDown.Value = 'mGy';
            app.Noise_loaded = 0;
            app.fileAddressNoise.Value = '';
            app.unitsNoiseDropDown.Value = 'C';
            app.DoseNoise_loaded = 0;
            app.fileAddressDoseN.Value = '';
            app.unitsDoseNoiseDropDown.Value = 'mGy';
            app.u_loaded = 0;
            app.fileAddressLinCoef.Value = '';
            app.unitsLinAttCoefDropDown.Value = 'cm^-1';
            app.E_u_loaded = 0;
            app.fileAddressEnergy.Value = '';
            app.unitsEnergyDropDown.Value = 'keV';
            app.ESF = 0;
            app.fileAddressESF.Value = '';
            app.fileAddressPixelCoord.Value = '';
            app.NoiseMatrix = 0;
            app.fileAddressDetNoise2D.Value = '';
            %constants
            app.DetectorAreaEditField.Value = 0; %mm^2
            app.thicknessEditField.Value = 0; %mm
            app.massAttCoefEditField.Value = 0;  
            app.massAttCoefByEnergyEditField.Value = 0;
            app.EmesEditField.Value = 0; %keV
            app.umesEditField.Value = 0;%cm^-1
            app.E_bandGapEditField.Value = 0; %eV
            app.E_pairEditField.Value = 0; % x band gap
            %Single pixel 
            app.minDoseEditField.Value = 0;%
            app.maxDoseEditField.Value = 1;%
            app.stepDoseEditField.Value = 0.01;%mGy
            app.DQEcurve1EditField.Value = 0; %photons
            app.DQEcurve2EditField.Value = 0; %photons
            app.DQEcurve3EditField.Value = 0; %photons
            app.unitsDoseDQEDropDown.Value = 'mGy';
            %array detector panel
            app.MTFlimitEditField.Value = 10; %lp/mm
            app.interceptCorrectionCheckBox.Value = 0;
            app.guess_A_EditField.Value = 0;
            app.guess_sigma_EditField.Value = 0;
            app.guess_u_EditField.Value = 0;
            app.fileAddressSignalTransient.Value = '';
            app.fileAddressTime.Value = '';
            app.RiseTimeEditField.Value = '';
            app.f3dB_EditField.Value = 0;
            app.guessTimeStartPointEditField.Value = 0;
            app.guessTimeStopPointEditField.Value = 0;
            

            else
            %Signal
            fileID = fopen(app.Example_constants{exampleNum, 1}, 'r');
            app.Signal_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressSignal.Value = app.Example_constants{exampleNum, 1};
            app.unitsSignalDropDown.Value = app.Example_constants{exampleNum, 2};
            %DoseSignal
            fileID = fopen(app.Example_constants{exampleNum, 3}, 'r');
            app.DoseSignal_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressDoseS.Value = app.Example_constants{exampleNum, 3};
            app.unitsDoseSignalDropDown.Value = app.Example_constants{exampleNum, 4};
            %Noise
            fileID = fopen(app.Example_constants{exampleNum, 5}, 'r');
            app.Noise_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressNoise.Value = app.Example_constants{exampleNum, 5};
            app.unitsNoiseDropDown.Value = app.Example_constants{exampleNum, 6};
            %DoseNoise
            fileID = fopen(app.Example_constants{exampleNum, 7}, 'r');
            app.DoseNoise_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressDoseN.Value = app.Example_constants{exampleNum, 7};
            app.unitsDoseNoiseDropDown.Value = app.Example_constants{exampleNum, 8};
            %LinAttCoef
            fileID = fopen(app.Example_constants{exampleNum, 9}, 'r');
            app.u_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressLinCoef.Value = app.Example_constants{exampleNum, 9};
            app.unitsLinAttCoefDropDown.Value = app.Example_constants{exampleNum, 10};
            %Energy
            fileID = fopen(app.Example_constants{exampleNum, 11}, 'r');
            app.E_u_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressEnergy.Value = app.Example_constants{exampleNum, 11};
            app.unitsEnergyDropDown.Value = app.Example_constants{exampleNum, 12};
            %ESF
            fileID = fopen(app.Example_constants{exampleNum, 13}, 'r');
            app.ESF = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressESF.Value = app.Example_constants{exampleNum, 13};
            %pixels
            fileID = fopen(app.Example_constants{exampleNum, 14}, 'r');
            app.coordinates_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressPixelCoord.Value = app.Example_constants{exampleNum, 14};
            app.unitsPixelsDropDown.Value = app.Example_constants{exampleNum, 15}; %um
            %app.pitchEditField.Value = app.Example_constants{exampleNum, 15}; %um
            %NoiseMatrix
            app.NoiseMatrix = readmatrix(app.Example_constants{exampleNum, 16});
            app.fileAddressDetNoise2D.Value = app.Example_constants{exampleNum, 16};
            %constants
            app.DetectorAreaEditField.Value = app.Example_constants{exampleNum, 17}; %mm^2
            app.thicknessEditField.Value = app.Example_constants{exampleNum, 18}; %mm
            app.massAttCoefEditField.Value = app.Example_constants{exampleNum, 19};  
            app.massAttCoefByEnergyEditField.Value = app.Example_constants{exampleNum, 20};
            app.EmesEditField.Value = app.Example_constants{exampleNum, 21}; %keV
            app.umesEditField.Value = app.Example_constants{exampleNum, 22};%cm^-1
            app.E_bandGapEditField.Value = app.Example_constants{exampleNum, 23}; %eV
            app.E_pairEditField.Value = app.Example_constants{exampleNum, 24}; % x band gap
            %Single pixel 
            app.minDoseEditField.Value = app.Example_constants{exampleNum, 25};%
            app.maxDoseEditField.Value = app.Example_constants{exampleNum, 26};%
            app.stepDoseEditField.Value = app.Example_constants{exampleNum, 27};%mGy
            app.DQEcurve1EditField.Value = app.Example_constants{exampleNum, 28}; %photons
            app.DQEcurve2EditField.Value = app.Example_constants{exampleNum, 29}; %photons
            app.DQEcurve3EditField.Value = app.Example_constants{exampleNum, 30}; %photons
            app.unitsDoseDQEDropDown.Value = app.Example_constants{exampleNum, 31};
            %array detector panel
            app.MTFlimitEditField.Value = app.Example_constants{exampleNum, 32}; %lp/mm
            app.interceptCorrectionCheckBox.Value = app.Example_constants{exampleNum, 33};
            app.guess_A_EditField.Value = app.Example_constants{exampleNum, 34};
            app.guess_sigma_EditField.Value = app.Example_constants{exampleNum, 35};
            app.guess_u_EditField.Value = app.Example_constants{exampleNum, 36};
            %transient
            fileID = fopen(app.Example_constants{exampleNum, 37}, 'r');
            app.SignalTransient_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressSignalTransient.Value = app.Example_constants{exampleNum, 37};
            fileID = fopen(app.Example_constants{exampleNum, 38}, 'r');
            app.Time_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressTime.Value = app.Example_constants{exampleNum, 38};
            app.unitsTimeDropDown.Value = app.Example_constants{exampleNum, 39};
            app.guessTimeStartPointEditField.Value = app.Example_constants{exampleNum, 40};
            app.guessTimeStopPointEditField.Value = app.Example_constants{exampleNum, 41};
            end
        end

        % Button pushed function: SignalTransientButton
        function SignalTransientButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.SignalTransient_loaded = fscanf(fileID,"%f"); %no unit
            fclose(fileID);
            app.fileAddressSignalTransient.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: TimeButton
        function TimeButtonPushed(app, event)
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.Time_loaded = fscanf(fileID,"%f"); 
            fclose(fileID);
            app.fileAddressTime.Value = file;
            app.commonPath = path;
        end

        % Button down function: Transient_plot
        function Transient_plotButtonDown(app, event)
            export(app,app.Transient_plot,app.Time./app.timeMultiplier,app.SignalTransient,'Photon count event');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {868, 868};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SignalButton = uibutton(app.GridLayout2, 'push');
            app.SignalButton.ButtonPushedFcn = createCallbackFcn(app, @SignalButtonPushed, true);
            app.SignalButton.Text = 'Signal';
            app.DosesignalButton = uibutton(app.GridLayout2, 'push');
            app.DosesignalButton.ButtonPushedFcn = createCallbackFcn(app, @DosesignalButtonPushed, true);
            app.DosesignalButton.Text = 'Dose(signal)';
            app.NoiseButton = uibutton(app.GridLayout2, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.DosenoiseButton = uibutton(app.GridLayout2, 'push');
            app.DosenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DosenoiseButtonPushed, true);
            app.DosenoiseButton.Text = 'Dose(noise)';
            app.FileinputLabel = uilabel(app.GridLayout2);
            app.FileinputLabel.Text = 'File input';
            app.LinattencoefButton = uibutton(app.GridLayout2, 'push');
            app.LinattencoefButton.ButtonPushedFcn = createCallbackFcn(app, @LinattencoefButtonPushed, true);
            app.LinattencoefButton.Text = 'Lin. atten. coef.';
            app.EnergyButton = uibutton(app.GridLayout2, 'push');
            app.EnergyButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyButtonPushed, true);
            app.EnergyButton.Text = 'Energy';
            app.ESFButton = uibutton(app.GridLayout2, 'push');
            app.ESFButton.ButtonPushedFcn = createCallbackFcn(app, @ESFButtonPushed, true);
            app.ESFButton.Text = 'ESF';
            app.PixelspositionButton = uibutton(app.GridLayout2, 'push');
            app.PixelspositionButton.ButtonPushedFcn = createCallbackFcn(app, @PixelspositionButtonPushed, true);
            app.PixelspositionButton.Text = 'Pixels (position)';
            app.DetectorNoise2DButton = uibutton(app.GridLayout2, 'push');
            app.DetectorNoise2DButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorNoise2DButtonPushed, true);
            app.DetectorNoise2DButton.Text = 'Detector Noise (2D)';
            app.fileAddressSignal = uitextarea(app.GridLayout2);
            app.fileAddressDoseS = uitextarea(app.GridLayout2);
            app.fileAddressNoise = uitextarea(app.GridLayout2);
            app.fileAddressDoseN = uitextarea(app.GridLayout2);
            app.fileAddressLinCoef = uitextarea(app.GridLayout2);
            app.fileAddressEnergy = uitextarea(app.GridLayout2);
            app.fileAddressESF = uitextarea(app.GridLayout2);
            app.fileAddressPixelCoord = uitextarea(app.GridLayout2);
            app.fileAddressDetNoise2D = uitextarea(app.GridLayout2);
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.unitsSignalDropDown = uidropdown(app.GridLayout2);
            app.unitsSignalDropDown.Items = {'C', 'mC', 'uC', 'a.u.', 'electrons'};
            app.unitsSignalDropDown.Value = 'C';
            app.unitsNoiseDropDown = uidropdown(app.GridLayout2);
            app.unitsNoiseDropDown.Items = {'C', 'mC', 'uC', 'a.u.', 'electrons'};
            app.unitsNoiseDropDown.Value = 'C';
            app.unitsDoseSignalDropDown = uidropdown(app.GridLayout2);
            app.unitsDoseSignalDropDown.Items = {'Gy', 'mGy', 'uGy', 'mR', 'photons', 'R'};
            app.unitsDoseSignalDropDown.Value = 'mGy';
            app.unitsDoseNoiseDropDown = uidropdown(app.GridLayout2);
            app.unitsDoseNoiseDropDown.Items = {'Gy', 'mGy', 'uGy', 'mR', 'R', 'photons'};
            app.unitsDoseNoiseDropDown.Value = 'mGy';
            app.unitsLinAttCoefDropDown = uidropdown(app.GridLayout2);
            app.unitsLinAttCoefDropDown.Items = {'cm^-1', 'm^-1'};
            app.unitsLinAttCoefDropDown.Value = 'cm^-1';
            app.unitsEnergyDropDown = uidropdown(app.GridLayout2);
            app.unitsEnergyDropDown.Items = {'eV', 'keV', 'MeV'};
            app.unitsEnergyDropDown.Value = 'keV';
            app.Detectorareamm2Label_2 = uilabel(app.GridLayout2);
            app.Detectorareamm2Label_2.Text = 'Detector area (mm^2)';
            app.DetectorAreaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaterialThicknessLabel_8 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_8.Text = 'Material thickness (mm)';
            app.thicknessEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaterialThicknessLabel_9 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_9.Text = {'<μ/ρ>, cm^2 g^-1'; '(averaged )'};
            app.massAttCoefEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaterialThicknessLabel_10 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_10.Text = {'X-ray energy'; 'during measurement (keV)'};
            app.EmesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaterialThicknessLabel_11 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_11.Text = 'Energy band-gap, eV';
            app.E_bandGapEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaterialThicknessLabel_12 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_12.Text = {'e-h pair creation'; 'energy'};
            app.E_pairEditField = uieditfield(app.GridLayout2, 'numeric');
            app.E_pairEditField.Value = 2.8;
            app.MaterialThicknessLabel_13 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_13.Text = '<E*μ/ρ>, kev cm^2 g^-1';
            app.massAttCoefByEnergyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaterialThicknessLabel_14 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_14.Text = 'μ at used energy (cm^-1)';
            app.umesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.xbandgapLabel = uilabel(app.GridLayout2);
            app.xbandgapLabel.Text = '  x band-gap';
            app.ExamplesDropDown = uidropdown(app.GridLayout2);
            app.ExamplesDropDown.Items = {'none', 'GOS', 'a-Se', 'Si', 'CdTe', 'MAPbI3'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.ExamplesDropDown.Value = 'none';
            app.unitsPixelsDropDown = uidropdown(app.GridLayout2);
            app.unitsPixelsDropDown.Items = {'um', 'mm'};
            app.unitsPixelsDropDown.Value = 'um';
            app.ExampleLabel = uilabel(app.GridLayout2);
            app.ExampleLabel.Text = 'Example:';
            app.Noise2DTextLabel = uilabel(app.GridLayout2);
            app.Noise2DTextLabel.Text = {'leave empty if the detector'; 'is photon-counting'};
            app.RightPanel = uipanel(app.GridLayout);
            app.VitaliiBartoshKostiantynSakhatskyiKovalenkoGroupETHZurichLabel = uilabel(app.RightPanel);
            app.VitaliiBartoshKostiantynSakhatskyiKovalenkoGroupETHZurichLabel.Text = '2025 Vitalii Bartosh, Kostiantyn Sakhatskyi, Kovalenko Group, ETH Zurich';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SinglepixelcharacterizationTab = uitab(app.TabGroup);
            app.SinglepixelcharacterizationTab.Title = 'Single pixel characterization';
            app.SignalToDose = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.SignalToDose, 'Signal/dose dependence')
            xlabel(app.SignalToDose, 'dose, Gy')
            ylabel(app.SignalToDose, 'Signal, C')
            zlabel(app.SignalToDose, 'Z')
            app.SignalToDose.ButtonDownFcn = createCallbackFcn(app, @SignalToDoseButtonDown, true);
            app.NoiseToDose = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.NoiseToDose, 'Sq. Noise/dose dependence')
            xlabel(app.NoiseToDose, 'dose, Gy')
            ylabel(app.NoiseToDose, 'Noise^2, C^2')
            zlabel(app.NoiseToDose, 'Z')
            app.NoiseToDose.ButtonDownFcn = createCallbackFcn(app, @NoiseToDoseButtonDown, true);
            app.DQE_dose_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.DQE_dose_plot, 'DQE/dose dependence')
            xlabel(app.DQE_dose_plot, 'dose, Gy')
            ylabel(app.DQE_dose_plot, 'DQE')
            zlabel(app.DQE_dose_plot, 'Z')
            app.DQE_dose_plot.ButtonDownFcn = createCallbackFcn(app, @DQE_dose_plotButtonDown, true);
            app.AE_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.AE_plot, 'Attenuation efficiency')
            xlabel(app.AE_plot, 'Energy, keV')
            ylabel(app.AE_plot, 'AE')
            zlabel(app.AE_plot, 'Z')
            app.AE_plot.ButtonDownFcn = createCallbackFcn(app, @AE_plotButtonDown, true);
            app.DE_E_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.DE_E_plot, 'DE/energy dependence')
            xlabel(app.DE_E_plot, 'Energy, keV')
            ylabel(app.DE_E_plot, 'DE_E')
            zlabel(app.DE_E_plot, 'Z')
            app.DE_E_plot.ButtonDownFcn = createCallbackFcn(app, @DE_E_plotButtonDown, true);
            app.NED_E_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.NED_E_plot, 'NED/energy dependence')
            xlabel(app.NED_E_plot, 'Energy, keV')
            ylabel(app.NED_E_plot, 'NED_E')
            zlabel(app.NED_E_plot, 'Z')
            app.NED_E_plot.YScale = 'log';
            app.NED_E_plot.ButtonDownFcn = createCallbackFcn(app, @NED_E_plotButtonDown, true);
            app.DQE_E_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.DQE_E_plot, 'DQE/energy dependence')
            xlabel(app.DQE_E_plot, 'Energy, keV')
            ylabel(app.DQE_E_plot, 'DQE_E')
            zlabel(app.DQE_E_plot, 'Z')
            app.DQE_E_plot.ButtonDownFcn = createCallbackFcn(app, @DQE_E_plotButtonDown, true);
            app.TheoreticalsensitivityTextAreaLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.TheoreticalsensitivityTextAreaLabel_2.Text = {'Theoretical '; 'sensitivity'};
            app.TheoreticalSensitivityTextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_5 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_5.Text = {'Detection'; 'efficiency'};
            app.DETextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_6 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_6.Text = {'Detection'; 'efficiency'; '(from noise analysis)'};
            app.DE_2TextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_7 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_7.Text = 'NED (uGy)';
            app.NEDdoseTextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_8 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_8.Text = 'NED (photons)';
            app.NEDphotonTextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.curve1EditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.curve1EditFieldLabel_2.Text = 'curve 1';
            app.DQEcurve1EditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.DQEcurve1EditField.Value = 10000;
            app.curve2EditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.curve2EditFieldLabel_2.Text = 'curve 2';
            app.DQEcurve2EditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.DQEcurve2EditField.Value = 100000;
            app.curve3EditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.curve3EditFieldLabel_2.Text = 'curve 3';
            app.DQEcurve3EditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.DQEcurve3EditField.Value = 1000000;
            app.SpecifythedoseinphotonsLabel = uilabel(app.SinglepixelcharacterizationTab);
            app.SpecifythedoseinphotonsLabel.Text = 'Dose in photons for DQE(E):';
            app.minEditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.minEditFieldLabel_2.Text = 'min';
            app.minDoseEditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.maxEditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.maxEditFieldLabel_2.Text = 'max';
            app.maxDoseEditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.maxDoseEditField.Value = 32;
            app.stepEditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.stepEditFieldLabel_2.Text = 'step';
            app.stepDoseEditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.stepDoseEditField.Value = 0.01;
            app.SetDoseRangeLabel = uilabel(app.SinglepixelcharacterizationTab);
            app.SetDoseRangeLabel.Text = 'Dose range for DQE';
            app.unitsDoseDQEDropDown = uidropdown(app.SinglepixelcharacterizationTab);
            app.unitsDoseDQEDropDown.Items = {'Gy', 'mGy', 'uGy', 'mR', 'R', 'photons'};
            app.unitsDoseDQEDropDown.Value = 'mGy';
            app.interceptCorrectionCheckBox = uicheckbox(app.SinglepixelcharacterizationTab);
            app.interceptCorrectionCheckBox.Text = 'intercept correction';
            app.onlyif0dosepointwasmeasuredLabel = uilabel(app.SinglepixelcharacterizationTab);
            app.onlyif0dosepointwasmeasuredLabel.Text = 'only if 0 dose point was measured';
            app.DetectionefficiencyLabel_9 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_9.Text = '1 photon dose';
            app.onePhotonDoseTextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.ArraydetectorcharacterizationTab = uitab(app.TabGroup);
            app.ArraydetectorcharacterizationTab.Title = 'Array detector characterization';
            app.ESF_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.ESF_plot, 'Edge spread function')
            xlabel(app.ESF_plot, 'distance, mm')
            ylabel(app.ESF_plot, 'ESF')
            zlabel(app.ESF_plot, 'Z')
            app.ESF_plot.ButtonDownFcn = createCallbackFcn(app, @ESF_plotButtonDown, true);
            app.LSF_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.LSF_plot, 'Linear spread function')
            xlabel(app.LSF_plot, 'distance, mm')
            ylabel(app.LSF_plot, 'LSF')
            zlabel(app.LSF_plot, 'Z')
            app.LSF_plot.ButtonDownFcn = createCallbackFcn(app, @LSF_plotButtonDown, true);
            app.MTF_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.MTF_plot, 'Modulation transfer function')
            xlabel(app.MTF_plot, 'spatial frequency, lp/mm')
            ylabel(app.MTF_plot, 'MTF')
            zlabel(app.MTF_plot, 'Z')
            app.MTF_plot.ButtonDownFcn = createCallbackFcn(app, @MTF_plotButtonDown, true);
            app.Noise2D_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.Noise2D_plot, 'Noise from the detector')
            xlabel(app.Noise2D_plot, 'pixel number')
            ylabel(app.Noise2D_plot, 'pixel number')
            zlabel(app.Noise2D_plot, 'Z')
            app.Noise2D_plot.ButtonDownFcn = createCallbackFcn(app, @Noise2D_plotButtonDown, true);
            app.NPS_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.NPS_plot, 'Noise power spectrum')
            xlabel(app.NPS_plot, 'spatial frequency, lp/mm')
            ylabel(app.NPS_plot, 'NPS')
            zlabel(app.NPS_plot, 'Z')
            app.NPS_plot.ButtonDownFcn = createCallbackFcn(app, @NPS_plotButtonDown, true);
            app.DQE_f_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.DQE_f_plot, 'Detective Quantum Efficiency')
            xlabel(app.DQE_f_plot, 'spatial frequency, lp/mm')
            ylabel(app.DQE_f_plot, 'DQE')
            zlabel(app.DQE_f_plot, 'Z')
            app.DQE_f_plot.ButtonDownFcn = createCallbackFcn(app, @DQE_f_plotButtonDown, true);
            app.NNPS_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.NNPS_plot, 'Normalized noise power spectrum')
            xlabel(app.NNPS_plot, 'spatial frequency, lp/mm')
            ylabel(app.NNPS_plot, 'NNPS')
            zlabel(app.NNPS_plot, 'Z')
            app.NNPS_plot.ButtonDownFcn = createCallbackFcn(app, @NNPS_plotButtonDown, true);
            app.limitlpmmLabel_2 = uilabel(app.ArraydetectorcharacterizationTab);
            app.limitlpmmLabel_2.Text = 'limit, lp/mm';
            app.MTFlimitEditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.MTFlimitEditField.Value = 10;
            app.ALabel = uilabel(app.ArraydetectorcharacterizationTab);
            app.ALabel.Text = 'A';
            app.guess_A_EditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.ALabel_2 = uilabel(app.ArraydetectorcharacterizationTab);
            app.ALabel_2.Text = 'μ';
            app.guess_u_EditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.ALabel_3 = uilabel(app.ArraydetectorcharacterizationTab);
            app.ALabel_3.Text = 'σ';
            app.guess_sigma_EditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.gaussanLabel = uilabel(app.ArraydetectorcharacterizationTab);
            app.gaussanLabel.Interpreter = 'latex';
            app.gaussanLabel.Text = 'f(x) = \frac{1}{\sigma \sqrt{2\pi}}e^{-\frac{1}{2}(\frac{x-\mu}{\sigma})^2}';
            app.guessLabel = uilabel(app.ArraydetectorcharacterizationTab);
            app.guessLabel.Text = 'guess parameters';
            app.fit_A_EditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.fit_sigma_EditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.fit_u_EditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.fitLabel = uilabel(app.ArraydetectorcharacterizationTab);
            app.fitLabel.Text = 'fit';
            app.ApproximationLabel = uilabel(app.ArraydetectorcharacterizationTab);
            app.ApproximationLabel.Visible = 'off';
            app.ApproximationLabel.Text = 'Approximation...';
            app.NPSTextLabel = uilabel(app.ArraydetectorcharacterizationTab);
            app.NPSTextLabel.Visible = 'off';
            app.NPSTextLabel.Text = {'For photon-counting detector the NPS is independent on spatial frequency (Proc SPIE Int Soc Opt Eng. 2017 Feb; 10132: 1013207.) '; ''; 'Therefore, normalized NPS is equal to 1/DE (McMullan, G. et al. Electron imaging with Medipix2 hybrid pixel detector. Ultramicroscopy 107, 401–413 (2007).)'};
            app.TextAreaLabel = uilabel(app.ArraydetectorcharacterizationTab);
            app.TextAreaLabel.Text = '';
            app.n1TextArea = uitextarea(app.ArraydetectorcharacterizationTab);
            app.n_1fracn_mathrmphotonpixelmathrmSNR_mathrmout1Acdotf_202Label = uilabel(app.ArraydetectorcharacterizationTab);
            app.n_1fracn_mathrmphotonpixelmathrmSNR_mathrmout1Acdotf_202Label.Interpreter = 'latex';
            app.n_1fracn_mathrmphotonpixelmathrmSNR_mathrmout1Acdotf_202Label.Text = '$n_1 = \frac{n_{\mathrm{photon/pixel}}(\mathrm{SNR}_{\mathrm{out}} = 1)}{A_{\mathrm{pixel}} \cdot f_{sp.resolution}^2}$ = ';
            app.TransientTab = uitab(app.TabGroup);
            app.TransientTab.Title = 'Photon count transient';
            app.Transient_plot = uiaxes(app.TransientTab);
            title(app.Transient_plot, 'Photon-counting event')
            xlabel(app.Transient_plot, 'time, us')
            ylabel(app.Transient_plot, 'Signal (normalized)')
            zlabel(app.Transient_plot, 'Z')
            app.Transient_plot.ButtonDownFcn = createCallbackFcn(app, @Transient_plotButtonDown, true);
            app.SignalTransientButton = uibutton(app.TransientTab, 'push');
            app.SignalTransientButton.ButtonPushedFcn = createCallbackFcn(app, @SignalTransientButtonPushed, true);
            app.SignalTransientButton.Text = 'Signal (transient)';
            app.fileAddressSignalTransient = uitextarea(app.TransientTab);
            app.TimeButton = uibutton(app.TransientTab, 'push');
            app.TimeButton.ButtonPushedFcn = createCallbackFcn(app, @TimeButtonPushed, true);
            app.TimeButton.Text = 'Time';
            app.unitsTimeDropDown = uidropdown(app.TransientTab);
            app.unitsTimeDropDown.Items = {'ns', 'us', 'ms', 's'};
            app.unitsTimeDropDown.Value = 'us';
            app.fileAddressTime = uitextarea(app.TransientTab);
            app.f_3dBHzLabel = uilabel(app.TransientTab);
            app.f_3dBHzLabel.Interpreter = 'tex';
            app.f_3dBHzLabel.Text = 'f_{3dB} (Hz)';
            app.f3dB_EditField = uieditfield(app.TransientTab, 'numeric');
            app.f3dB_EditField.Editable = 'off';
            app.guessvalueLabel = uilabel(app.TransientTab);
            app.guessvalueLabel.Text = 'Signal 10% ';
            app.guessTimeStartPointEditField = uieditfield(app.TransientTab, 'numeric');
            app.guessvalueLabel_2 = uilabel(app.TransientTab);
            app.guessvalueLabel_2.Text = 'Signal 90%';
            app.guessTimeStopPointEditField = uieditfield(app.TransientTab, 'numeric');
            app.timeGuessValuesLabel = uilabel(app.TransientTab);
            app.timeGuessValuesLabel.Text = 'Time guess values';
            app.RisetimeEditFieldLabel = uilabel(app.TransientTab);
            app.RisetimeEditFieldLabel.Interpreter = 'tex';
            app.RisetimeEditFieldLabel.Text = 'Rise time';
            app.RiseTimeEditField = uieditfield(app.TransientTab, 'text');
            app.ColorButton = uibutton(app.RightPanel, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perovXimager_VER3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end